classdef collaborativeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RadiosondeAnalysisApp       matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        AnalysisaltituderangeMenu   matlab.ui.container.Menu
        SavedirectoryMenu           matlab.ui.container.Menu
        AboutthisappMenu            matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        gwaveTab                    matlab.ui.container.Tab
        powerSpectrumPlot           matlab.ui.control.UIAxes
        TropopauseEditFieldLabel    matlab.ui.control.Label
        TropopauseEditField         matlab.ui.control.NumericEditField
        PBLEditFieldLabel           matlab.ui.control.Label
        PBLEditField                matlab.ui.control.NumericEditField
        WindshearEditFieldLabel     matlab.ui.control.Label
        WindshearEditField          matlab.ui.control.EditField
        LowleveljetsEditFieldLabel  matlab.ui.control.Label
        LowleveljetsEditField       matlab.ui.control.EditField
        SavedataButton              matlab.ui.control.Button
        gravityWavePlot             matlab.ui.control.UIAxes
        pblTab                      matlab.ui.container.Tab
        pbl_pt_plot                 matlab.ui.control.UIAxes
        pbl_ri_plot                 matlab.ui.control.UIAxes
        pbl_sh_plot                 matlab.ui.control.UIAxes
        pbl_temp_plot               matlab.ui.control.UIAxes
        pbl_CheckBox_pt             matlab.ui.control.CheckBox
        pbl_CheckBox_ri             matlab.ui.control.CheckBox
        pbl_CheckBox_sh             matlab.ui.control.CheckBox
        PBLHeightsLabel             matlab.ui.control.Label
        RiLabel                     matlab.ui.control.Label
        PtLabel                     matlab.ui.control.Label
        HuLabel                     matlab.ui.control.Label
        TypeLabel                   matlab.ui.control.Label
        timeseriesTab               matlab.ui.container.Tab
        waveletgifTab               matlab.ui.container.Tab
        LoadfileButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentFile % File currently being analyzed.
        lowerCutOffAltitude = 0;
        upperCutOffAltitude = 40;
        saveDirectory = 'gravityWaveData';
        tropopause = [];
        lowLevelJets = [];
        windShearLevels = [];
        planetaryBoundaryLayer = [];
        latitudeArray = [];
        longitudeArray = [];
        data = []; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           addpath('wave_matlab/');
           warning('off', 'MATLAB:polyfit:RepeatedPointsOrRescale');
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            if isequal(file, 0)
                % disp('User selected Cancel');
                return 
            end
            cla(app.powerSpectrumPlot);
            cla(app.gravityWavePlot);
            %Update PBL Tab View
            %Requires pbl_calculation.m and readSondeFile.m (not included with .mlapp file)
            [PBLpt, PBLri, PBLsh, PBL_Type] = pbl_calculation(fullfile(path,file),app.pbl_CheckBox_pt.Value,...
                app.pbl_CheckBox_ri.Value, app.pbl_CheckBox_sh.Value, ...
                app.pbl_pt_plot, app.pbl_ri_plot, app.pbl_sh_plot, ...
                app.pbl_temp_plot);
            app.RiLabel.Text = sprintf('Ri: %.2f m',PBLri);
            app.PtLabel.Text = sprintf('Pt: %.2f m',PBLpt);
            app.HuLabel.Text = sprintf('Hu: %.2f m',PBLsh);
            app.TypeLabel.Text = sprintf('%s',PBL_Type);
            
            app.currentFile = fullfile(path, file);
            showPowerSurfaces = false;
            save = false;
            [~, titleName, ~] = fileparts(app.currentFile);
            set(app.powerSpectrumPlot, 'YScale', 'log')
            titleString = sprintf("%s", titleName);
            title(app.powerSpectrumPlot, titleString, 'Interpreter', 'none');
            [app.latitudeArray, app.longitudeArray, altitude, ...
                app.data, waveletTransform, clippedAlt, ...
                gWaveLocations] = doAnalysis(app.currentFile, save, app.saveDirectory, showPowerSurfaces, app.lowerCutOffAltitude*1000, app.upperCutOffAltitude*1000);
            if isempty(app.data)
                title(app.gravityWavePlot, 'No gravity waves detected');
                msgbox(sprintf("No gravity waves detected for %s", titleName));
            end
            plotGravityWaveData(app.data, altitude, waveletTransform, gWaveLocations, clippedAlt, app.powerSpectrumPlot, app.gravityWavePlot);  
        end

        % Value changed function: TropopauseEditField
        function TropopauseEditFieldValueChanged(app, event)
           try    
            app.tropopause = app.TropopauseEditField.Value;
            xs = xlim(app.gravityWavePlot);
            ys = zeros(size(xs), 'like', xs) + app.tropopause;
            tropoPauseLine = plot(app.gravityWavePlot, xs, ys, 'c');
            legend(app.gravityWavePlot, tropoPauseLine, 'tropopause')
        catch
            helpdlg("Tropopause edit field only accepts altitude of tropopause in km.")
           end
        end

        % Value changed function: PBLEditField
        function PBLEditFieldValueChanged(app, event)
        try     
            app.planetaryBoundaryLayer = app.PBLEditField.Value;
            xs = xlim(app.gravityWavePlot);
            ys = zeros(size(xs), 'like', xs) + app.planetaryBoundaryLayer;
            pblLine = plot(app.gravityWavePlot, xs, ys, 'm');
            legend(app.gravityWavePlot, pblLine, 'pbl')
        catch
            helpdlg("PBL edit field only accepts altitude of tropopause in km.")
            end
            
        end

        % Value changed function: WindshearEditField
        function WindshearEditFieldValueChanged(app, event)
           try    
            value = app.WindshearEditField.Value;
            s = split(value, ',');
            app.windShearLevels = cellfun(@str2num,s(1:end));
            app.windShearLevels = app.windShearLevels;
            xs = xlim(app.gravityWavePlot);
            for i=1:size(app.windShearLevels)
                ys = zeros(size(xs), 'like', xs) + app.windShearLevels(i);
                windShearLine = plot(app.gravityWavePlot, xs, ys, 'g');
                legend(app.gravityWavePlot, windShearLine, 'wind shear')
            end
        catch
           helpdlg("Wind shear edit field only accepts a comma-delimited list of wind shear levels in km.")
           end
        end

        % Value changed function: LowleveljetsEditField
        function LowleveljetsEditFieldValueChanged(app, event)
            try
            value = app.LowleveljetsEditField.Value;
            s = split(value, ',');
            app.lowLevelJets = cellfun(@str2num,s(1:end));
            app.lowLevelJets = app.lowLevelJets;
            xs = xlim(app.gravityWavePlot);
            for i=1:size(app.lowLevelJets)
                ys = zeros(size(xs), 'like', xs) + app.lowLevelJets(i);
                lowLevelJet = plot(app.gravityWavePlot, xs, ys, 'k');
                legend(app.gravityWavePlot, lowLevelJet, 'low level jet')
            end
            catch
                helpdlg("Low level jets edit field only accepts a comma-delimited list of jet levels in km.")
            end
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            if isempty(app.tropopause)
                app.tropopause = NaN;
            end
            if isempty(app.planetaryBoundaryLayer)
                app.planetaryBoundaryLayer = NaN;
            end
            if isempty(app.windShearLevels)
               app.windShearLevels = NaN;
            end
            if isempty(app.lowLevelJets)
               app.lowLevelJets = NaN;
            end
            maxLength = max(size(app.windShearLevels), size(app.lowLevelJets));
            maxLength = maxLength(1, 1);
            if maxLength == 0
                maxLength = 1;
            end
            if ~isfolder(app.saveDirectory)
                status = mkdir(app.saveDirectory);
                if status == 0
                    helpdlg(sprintf("Could not make directory %s\n. Choose a new directory name.", app.saveDirectory));
                end
            end
            
            llj = zeros(1, maxLength);
            llj(1:size(app.lowLevelJets)) = app.lowLevelJets*1000;
            wsl = zeros(1, maxLength);
            wsl(1:size(app.windShearLevels)) = app.windShearLevels*1000;
            tropo = zeros(1, maxLength);
            tropo(1) = app.tropopause*1000;
            pbl = zeros(1, maxLength);
            pbl(1) = app.planetaryBoundaryLayer*1000;
            header = {'wind_shear_levels', 'low_level_jets', 'tropopause', 'planetary_boundary_layer'};
            tbl = [wsl; llj; tropo; pbl]';
            dataBlock = array2table(tbl, 'VariableNames', header);
            [~, saveFileName, ~] = fileparts(app.currentFile);
            saveFileNameSignificantLevels = strcat(saveFileName, '_significant_levels.csv');
            saveFileNameGravityWaveData = strcat(saveFileName, '_gravity_wave_parameters.csv');
            saveFileNameSignificantLevels = fullfile(app.saveDirectory, saveFileNameSignificantLevels);
            saveFileNameGravityWaveData = fullfile(app.saveDirectory, saveFileNameGravityWaveData);
            
            if isfile(saveFileNameSignificantLevels)
                s = sprintf("File %s already exists. Would you like to overwrite the data? If no, levels are appended to the file.", saveFileNameSignificantLevels);
                answer = questdlg(s, 'Overwrite or append', 'Yes', 'No', 'Yes');
                switch answer
                    case 'Yes'
                       writetable(dataBlock, saveFileNameSignificantLevels);
                    case 'No'
                        dlmwrite(saveFileNameSignificantLevels, tbl, 'delimiter', ',', '-append');
                end
            else
                writetable(dataBlock, saveFileNameSignificantLevels);
            end
            writetable(app.data, saveFileNameGravityWaveData);
        end

        % Menu selected function: AnalysisaltituderangeMenu
        function AnalysisaltituderangeMenuSelected(app, event)
           x = inputdlg({'Lower altitude bound (km)', 'Upper altitude bound (km). A value of 40 goes to the end of the sounding.'}, 'Altitude range selection', [1 25; 1, 25], {num2str(app.lowerCutOffAltitude), num2str(app.upperCutOffAltitude)});
           if isempty(x)
               return;
           end
           app.lowerCutOffAltitude = str2double(x{1})*1000;
           app.upperCutOffAltitude = str2double(x{2})*1000;
        end

        % Menu selected function: SavedirectoryMenu
        function SavedirectoryMenuSelected(app, event)
           prompt = {'Enter a directory to save data in'};
           dlgtitle = 'Directory';
           definput = {'gravityWaveData'};
           opts.Interpreter = 'tex';
           app.saveDirectory = inputdlg(prompt,dlgtitle,[1 40],definput,opts);
           if isempty(app.saveDirectory)
               return
           end
           app.saveDirectory = app.saveDirectory{1};
        end

        % Menu selected function: AboutthisappMenu
        function AboutthisappMenuSelected(app, event)
            msgstring = fileread('readmeGUI.txt');
            msgbox(msgstring, 'About this app','help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RadiosondeAnalysisApp = uifigure('Visible', 'off');
            app.RadiosondeAnalysisApp.Name = 'Radiosonde Analysis App';
            app.Menu = uimenu(app.RadiosondeAnalysisApp);
            app.Menu.Text = 'Menu';
            app.AnalysisaltituderangeMenu = uimenu(app.Menu);
            app.AnalysisaltituderangeMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisaltituderangeMenuSelected, true);
            app.AnalysisaltituderangeMenu.Text = 'Analysis altitude range';
            app.SavedirectoryMenu = uimenu(app.Menu);
            app.SavedirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedirectoryMenuSelected, true);
            app.SavedirectoryMenu.Text = 'Save directory';
            app.AboutthisappMenu = uimenu(app.Menu);
            app.AboutthisappMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutthisappMenuSelected, true);
            app.AboutthisappMenu.Text = 'About this app';
            app.TabGroup = uitabgroup(app.RadiosondeAnalysisApp);
            app.gwaveTab = uitab(app.TabGroup);
            app.gwaveTab.Title = 'g-wave';
            app.powerSpectrumPlot = uiaxes(app.gwaveTab);
            title(app.powerSpectrumPlot, '')
            xlabel(app.powerSpectrumPlot, '')
            ylabel(app.powerSpectrumPlot, '')
            app.TropopauseEditFieldLabel = uilabel(app.gwaveTab);
            app.TropopauseEditFieldLabel.Text = 'Tropopause';
            app.TropopauseEditField = uieditfield(app.gwaveTab, 'numeric');
            app.TropopauseEditField.ValueChangedFcn = createCallbackFcn(app, @TropopauseEditFieldValueChanged, true);
            app.TropopauseEditField.Tooltip = {'Enter tropopause height in km.'};
            app.PBLEditFieldLabel = uilabel(app.gwaveTab);
            app.PBLEditFieldLabel.Text = 'PBL';
            app.PBLEditField = uieditfield(app.gwaveTab, 'numeric');
            app.PBLEditField.ValueChangedFcn = createCallbackFcn(app, @PBLEditFieldValueChanged, true);
            app.PBLEditField.Tooltip = {'PBL height in km'};
            app.WindshearEditFieldLabel = uilabel(app.gwaveTab);
            app.WindshearEditFieldLabel.Text = 'Wind shear';
            app.WindshearEditField = uieditfield(app.gwaveTab, 'text');
            app.WindshearEditField.ValueChangedFcn = createCallbackFcn(app, @WindshearEditFieldValueChanged, true);
            app.WindshearEditField.Tooltip = {'Wind shear (km). Enter multiple values as comma-delimited list'};
            app.LowleveljetsEditFieldLabel = uilabel(app.gwaveTab);
            app.LowleveljetsEditFieldLabel.Text = 'Low level jets';
            app.LowleveljetsEditField = uieditfield(app.gwaveTab, 'text');
            app.LowleveljetsEditField.ValueChangedFcn = createCallbackFcn(app, @LowleveljetsEditFieldValueChanged, true);
            app.LowleveljetsEditField.Tooltip = {'Enter multiple values as a comma delimited list'};
            app.SavedataButton = uibutton(app.gwaveTab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.gravityWavePlot = uiaxes(app.gwaveTab);
            title(app.gravityWavePlot, '')
            xlabel(app.gravityWavePlot, '')
            ylabel(app.gravityWavePlot, '')
            app.gravityWavePlot.XTick = [];
            app.gravityWavePlot.XTickLabel = '';
            app.pblTab = uitab(app.TabGroup);
            app.pblTab.Title = 'pbl';
            app.pbl_pt_plot = uiaxes(app.pblTab);
            title(app.pbl_pt_plot, 'Title')
            xlabel(app.pbl_pt_plot, 'X')
            ylabel(app.pbl_pt_plot, 'Y')
            app.pbl_ri_plot = uiaxes(app.pblTab);
            title(app.pbl_ri_plot, 'Title')
            xlabel(app.pbl_ri_plot, 'X')
            ylabel(app.pbl_ri_plot, 'Y')
            app.pbl_sh_plot = uiaxes(app.pblTab);
            title(app.pbl_sh_plot, 'Title')
            xlabel(app.pbl_sh_plot, 'X')
            ylabel(app.pbl_sh_plot, 'Y')
            app.pbl_temp_plot = uiaxes(app.pblTab);
            title(app.pbl_temp_plot, 'Title')
            xlabel(app.pbl_temp_plot, 'X')
            ylabel(app.pbl_temp_plot, 'Y')
            app.pbl_CheckBox_pt = uicheckbox(app.pblTab);
            app.pbl_CheckBox_pt.Text = 'Pt Gradient Plot';
            app.pbl_CheckBox_pt.Value = true;
            app.pbl_CheckBox_ri = uicheckbox(app.pblTab);
            app.pbl_CheckBox_ri.Text = 'Richardson Plot';
            app.pbl_CheckBox_ri.Value = true;
            app.pbl_CheckBox_sh = uicheckbox(app.pblTab);
            app.pbl_CheckBox_sh.Text = 'sHu Gradient Plot';
            app.pbl_CheckBox_sh.Value = true;
            app.PBLHeightsLabel = uilabel(app.pblTab);
            app.PBLHeightsLabel.Text = 'PBL Heights:';
            app.RiLabel = uilabel(app.pblTab);
            app.RiLabel.Text = 'Ri:';
            app.PtLabel = uilabel(app.pblTab);
            app.PtLabel.Text = 'Pt: ';
            app.HuLabel = uilabel(app.pblTab);
            app.HuLabel.Text = 'Hu: ';
            app.TypeLabel = uilabel(app.pblTab);
            app.TypeLabel.Text = 'Type:';
            app.timeseriesTab = uitab(app.TabGroup);
            app.timeseriesTab.Title = 'time series';
            app.waveletgifTab = uitab(app.TabGroup);
            app.waveletgifTab.Title = 'wavelet-gif';
            app.LoadfileButton = uibutton(app.RadiosondeAnalysisApp, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            % Show the figure after all components are created
            app.RadiosondeAnalysisApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = collaborativeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RadiosondeAnalysisApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RadiosondeAnalysisApp)
        end
    end
end